classdef zagrafa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        zagrafaUIFigure  matlab.ui.Figure
        Label            matlab.ui.control.Label
        text             matlab.ui.control.EditField
        Button           matlab.ui.control.Button
        zagrf1           matlab.ui.control.EditField
        zagrf2           matlab.ui.control.EditField
        zagrf3           matlab.ui.control.EditField
        Label_2          matlab.ui.control.Label
        Button_2         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        githubButton     matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Label_3          matlab.ui.control.Label
    end

    methods (Access = private)

        % Value changed function: text
        function textValueChanged(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
              
            value = app.text.Value;
           
            %----------------زخرفة رقم 1------------%
            s = strrep(value,'ض', 'ضِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ص', 'صِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ث', 'ثِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ق', 'قِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ف', 'فِ͒ـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'غ', 'غِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ع', 'عِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'خ', 'خِ̐ـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ح', 'حِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ج', 'جِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ش', 'شِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'س', 'سِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ي', 'يِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ب', 'بِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ل', 'لِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ا', 'آ'); 
            s = strrep(s,'ت', 'تِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ن', 'نِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'م', 'مِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ك', 'ڪِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ط', 'طِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ذ', 'ذِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ظ', 'ظِـٰٚـِْ✮ِـٰٚـِْ'); 
            s = strrep(s,'ظ', 'ظِـٰٚـِْ✮ِـٰٚـِْ');
            s = strrep(s,'ه','ۿۿہ'); 
            app.zagrf1.Value = s;
           %---------------زخرفة رقم 2------------------% 
            s2 = strrep(value,'خ', 'خِٰ̐ـِۢ'); 
            s2 = strrep(s2,'ض', 'ضِٰـِۢ'); 
            s2 = strrep(s2,'ص', 'صِٰـِۢ'); 
            s2 = strrep(s2,'ث', 'ثِٰـِۢ'); 
            s2 = strrep(s2,'ق', 'قِٰـِۢ'); 
            s2 = strrep(s2,'ف', 'فِٰ͒ـِۢ'); 
            s2 = strrep(s2,'غ', 'غِٰـِۢ'); 
            s2 = strrep(s2,'ع', 'عِٰـِۢ'); 
            s2 = strrep(s2,'ح', 'حِٰـِۢ'); 
            s2 = strrep(s2,'ج', 'جِٰـِۢ'); 
            s2 = strrep(s2,'ش', 'شِٰـِۢ'); 
            s2 = strrep(s2,'س', 'سِٰـِۢ'); 
            s2 = strrep(s2,'ي', 'يِٰـِۢ'); 
            s2 = strrep(s2,'ب', 'بِٰـِۢ'); 
            s2 = strrep(s2,'ل', 'لِٰـِۢ'); 
            s2 = strrep(s2,'ا', 'آ'); 
            s2 = strrep(s2,'ت', 'تِٰـِۢ'); 
            s2 = strrep(s2,'ن', 'نِٰـِۢ'); 
            s2 = strrep(s2,'م', 'مِٰـِۢ'); 
            s2 = strrep(s2,'ك', 'ڪِٰـِۢ'); 
            s2 = strrep(s2,'ط', 'طِٰـِۢ'); 
            s2 = strrep(s2,'ظ', 'ظِٰـِۢ'); 
            s2 = strrep(s2,'ه','ۿۿہ'); 
            app.zagrf2.Value = s2;
             %---------------زخرفة رقم 3------------------% 
            s3 = strrep(value,'ض', 'ض֠ــۢ͜ـٰ̲ـ'); 
            s = strrep(s3,'ص', 'ص֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ث', 'ث֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ق', 'ق֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ف', 'ف͒֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'غ', 'غ֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ع', 'ع֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'خ', 'خ̐֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ح', 'ح֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ج', 'ج֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ش', 'ش֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'س', 'س֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ي', 'ي֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ب', 'ب֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ل', 'ل֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ا', 'آ'); 
            s3 = strrep(s3,'ت', 'ت֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ن', 'ن֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'م', 'م֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ك', 'ڪ֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ط', 'ط֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ظ', 'ظ֠ــۢ͜ـٰ̲ـ'); 
            s3 = strrep(s3,'ه', '໋۠هہؚ'); 
            app.zagrf3.Value = s3;
        
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            web('https://www.5ldwn.tk/', '-browser')
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            web('https://www.instagram.com/5ldwn/', '-browser')
        end

        % Button pushed function: githubButton
        function githubButtonPushed(app, event)
            web('https://github.com/5ldwn', '-browser')
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            web('https://t.me/i96ha', '-browser')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.zagrafaUIFigure = uifigure;
            app.zagrafaUIFigure.Name = 'zagrafa';
            setAutoResize(app, app.zagrafaUIFigure, true)
            app.Label = uilabel(app.zagrafaUIFigure);
            app.Label.Text = 'زخرفة النصوص ';
            app.text = uieditfield(app.zagrafaUIFigure, 'text');
            app.text.ValueChangedFcn = createCallbackFcn(app, @textValueChanged, true);
            app.Button = uibutton(app.zagrafaUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'زخرف';
            app.zagrf1 = uieditfield(app.zagrafaUIFigure, 'text');
            app.zagrf2 = uieditfield(app.zagrafaUIFigure, 'text');
            app.zagrf3 = uieditfield(app.zagrafaUIFigure, 'text');
            app.Label_2 = uilabel(app.zagrafaUIFigure);
            app.Label_2.Text = 'ادخل النص هنا';
            app.Button_2 = uibutton(app.zagrafaUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'الموقع الشخصي للمبرمج';
            app.Button_3 = uibutton(app.zagrafaUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'حساب الانستغرام';
            app.githubButton = uibutton(app.zagrafaUIFigure, 'push');
            app.githubButton.ButtonPushedFcn = createCallbackFcn(app, @githubButtonPushed, true);
            app.githubButton.Text = 'github ';
            app.Button_4 = uibutton(app.zagrafaUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'قناة التلكرام';
            app.Label_3 = uilabel(app.zagrafaUIFigure);
            app.Label_3.Text = 'جميع حقوق البرنامج محفوظة للمبرمج خلدون 2022';
        end
    end

    methods (Access = public)

        % Construct app
        function app = zagrafa()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.zagrafaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.zagrafaUIFigure)
        end
    end
end